classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AverageofdaysSliderLabel   matlab.ui.control.Label
        AverageofdaysSlider        matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
    end

        
    properties (Access = private)                                             
        CasesDeaths         % list of cases and deths,[cases, deaths]        
        CasesVector         % Cases vector from [Cases, Death]
        DeathsVector        % Deaths Vector from [Cases, Deaths]        
        AveragesCases       %moving mean of cases vector and averaging window
        AveragesDeaths      %moving mean of deaths vector and averaging window 
        
        AveragingWindow     %'n' in nth moving mean
        VectorX             % X-axis plot values
        
        CSTitle                      %to print *Country* or *State* title         
        mAvgTitle                    %to print *no of time* moving mean title        
    end

    
    methods (Access = private)   
        function PlotData(app) 
            
            %%*****Setting axix values*****%%
            app.UIAxes.reset;            
            xlim(app.UIAxes,[app.VectorX(1),app.VectorX(end)]);
            xlim(app.UIAxes,'manual');
            app.UIAxes.YLimMode='auto'; 
            app.UIAxes.YTickMode='auto';
            grid(app.UIAxes,'on');
            datetick(app.UIAxes,'x','mm/dd',"keeplimits");
            set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').','%f'));
            %%*****End of Setting Axis*****%%
                                            
                                                        %% total 6 case of (radio buttons) %%
            cc=int8('Cumulative Number of Cases in ');              %cc=Cumulative-Cases
            cd=int8('Cumulative Number of Deaths in ');             %d=Cumulative-Deaths
            ccd=int8('Cumulative Number of Cases and Deaths in ');  %ccd=cumu-cases & Death
            dc=int8('Daily Number of Cases in ');                   %dc=Daily-Cases
            dd=int8('Daily Number of Deaths in ');                  %dd=Daily-Death
            dcd=int8('Daily Number of Cases and Deaths in ');       %dcd=daily-cases-deaths
            
        %%***** Total 6- cases to plot data *****%%            
            if app.CasesButton.Value && app.CumulativeButton.Value
                app.ComputeCumulative;
                app.ComputeAverages;
                bar(app.UIAxes,app.VectorX,app.AveragesCases,'b'); %bar plot for Cases
                num_title=[cc app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
            elseif app.CasesButton.Value && app.DailyButton.Value
                app.ComputeDaily;
                app.ComputeAverages;
                bar(app.UIAxes,app.VectorX,app.AveragesCases,'b'); %bar plot for Cases
                num_title=[dc app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
                
            elseif app.DeathsButton.Value && app.CumulativeButton.Value
                app.ComputeCumulative;
                app.ComputeAverages;
                plot(app.UIAxes,app.VectorX,app.AveragesDeaths,'r'); %plot for deaths
                num_title=[cd app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
            elseif app.DeathsButton.Value && app.DailyButton.Value
                app.ComputeDaily;
                app.ComputeAverages;
                plot(app.UIAxes,app.VectorX,app.AveragesDeaths,'r'); %plot for deaths
                num_title=[dd app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
                
%                 ****in these below two cases both side axes plotted***
            elseif app.BothButton.Value && app.CumulativeButton.Value
                app.ComputeCumulative;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.VectorX,app.AveragesCases,'b');      %bar plot for Cases
                app.UIAxes.YColor='blue';                              %or [0 0 1]
                app.UIAxes.YLimMode='Auto';
                app.UIAxes.YTickMode='auto';
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').','%f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.VectorX,app.AveragesDeaths,'r');    %plot for deaths
                app.UIAxes.YColor='red';                                % or [1,0,0]
                num_title=[ccd app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
                
            elseif app.BothButton.Value && app.DailyButton.Value
                app.ComputeDaily;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.VectorX,app.AveragesCases,'b');      %bar plot for Cases
                app.UIAxes.YColor='blue';
                app.UIAxes.YLimMode='Auto';
                app.UIAxes.YTickMode='auto';
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').','%f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.VectorX,app.AveragesDeaths,'r');    %plot for deaths
                app.UIAxes.YColor='red';
                num_title=[dcd app.CSTitle app.mAvgTitle];
                title(app.UIAxes, char(num_title),"HorizontalAlignment", "center");
            end 
            
            %%*****end of plotting all cases******%%
            
            hold(app.UIAxes,'off');
        end
    
        function ComputeAverages(app)
            app.AveragesCases=movmean(app.CasesVector,app.AveragingWindow);
            app.AveragesDeaths=movmean(app.DeathsVector,app.AveragingWindow);       
        end
    
        function ComputeDaily(app)
            app.ComputeCumulative;
            x=app.CasesVector-circshift(app.CasesVector,1);
            x(1)=app.CasesVector(1);
            k=logical(x<0);
            x(k)=0;
            app.CasesVector=x;
            y=app.DeathsVector-circshift(app.DeathsVector,1);
            y(1)=app.DeathsVector(1);
            g=logical(y<0);
            y(g)=0;
            app.DeathsVector=y;
        end       
    
        function ComputeCumulative(app)
            Item_Selected=GLOBAL(app.CountryListBox.Value);
            [~,n2]=ismember(app.StateorRegionListBox.Value, app.StateorRegionListBox.Items);
            [~,n1]=ismember(app.CountryListBox.Value, app.CountryListBox.Items);
            if n1==1
                app.CasesDeaths=Item_Selected.globalCasesDeaths; 
                app.CasesVector=app.CasesDeaths(1,:);
                app.DeathsVector=app.CasesDeaths(2,:);  
            else
                app.CasesVector=Item_Selected.Cases_Vector(Item_Selected.StateIndex(n2));
                app.DeathsVector=Item_Selected.Deaths_Vector(Item_Selected.StateIndex(n2));         
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            globalData=GLOBAL('Global');
            app.CountryListBox.Items=(unique(globalData.Country,'stable'));
            app.StateorRegionListBox.Items=(unique(globalData.State,'stable'));
            
            app.CasesDeaths=globalData.globalCasesDeaths; 
            app.CasesVector=app.CasesDeaths(1,:);
            app.DeathsVector=app.CasesDeaths(2,:);
            
            startDate=datenum(globalData.Date{1});  %x axis starting
            endDate=datenum(globalData.Date{end});  %x axis end
            
            app.VectorX=linspace(startDate,endDate,length(globalData.Date)); %x-axix vector
            app.AveragingWindow=app.AverageofdaysSlider.Value;
            app.StateorRegionListBoxValueChanged;
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            Item_Selected=GLOBAL(app.CountryListBox.Value);
            app.StateorRegionListBox.Items=(unique(Item_Selected.State,'stable'));
            app.StateorRegionListBoxValueChanged;                        
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            [~, n] = ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);
            if n==1                   %when states are missing the it will be n==1
                app.CSTitle=app.CountryListBox.Value;
            else
                app.CSTitle=app.StateorRegionListBox.Value;
            end
            app.PlotData;         
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.PlotData;            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.PlotData;            
        end

        % Value changing function: AverageofdaysSlider
        function AverageofdaysSliderValueChanging(app, event)
            app.AveragingWindow=event.Value;
            if app.AveragingWindow~=1
                app.mAvgTitle=[int8(' (')  ,int8(char(string(round(app.AveragingWindow)))),int8('-Day Mean)')]; %no to char to print move mean time
            else
                app.mAvgTitle=int8('');   %AveragingWindow=1no averaging so empty
            end
            
            app.PlotData;                        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Covid-19 Data Visualization (Data Source : Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AverageofdaysSliderLabel = uilabel(app.UIFigure);
            app.AverageofdaysSliderLabel.Text = 'Average # of days';
            app.AverageofdaysSlider = uislider(app.UIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AverageofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanging, true);
            app.AverageofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end